classdef OlympiaConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LogpanelPanel                 matlab.ui.container.Panel
        ExportlogButton               matlab.ui.control.Button
        ClearlogButton                matlab.ui.control.Button
        LogTextArea                   matlab.ui.control.TextArea
        SavingLabel                   matlab.ui.control.Label
        OperationsLabel               matlab.ui.control.Label
        MainsettingsLabel             matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        TaskSelectionButtonGroup      matlab.ui.container.ButtonGroup
        GeneralButton                 matlab.ui.control.ToggleButton
        LongjumpingButton             matlab.ui.control.ToggleButton
        RunningButton                 matlab.ui.control.ToggleButton
        WalkingButton                 matlab.ui.control.ToggleButton
        SbjBrowseButton               matlab.ui.control.Button
        SbjPathEditField              matlab.ui.control.EditField
        ConfirmCalObjButton           matlab.ui.control.Button
        AddCalObjButton               matlab.ui.control.Button
        CalobjDropDown                matlab.ui.control.DropDown
        SelectthebiomechanicalmodeltobeappliedLabel_2  matlab.ui.control.Label
        AddModelButton                matlab.ui.control.Button
        ConfirmModelButton            matlab.ui.control.Button
        ModelDropDown                 matlab.ui.control.DropDown
        SelectthebiomechanicalmodeltobeappliedLabel  matlab.ui.control.Label
        ConfigurationfileformatLabel  matlab.ui.control.Label
        jsonCheckBox                  matlab.ui.control.CheckBox
        xmlCheckBox                   matlab.ui.control.CheckBox
        SelectpathtostoretheconfigfileButton  matlab.ui.control.Button
        Path2saveEditField            matlab.ui.control.EditField
        ConfignameEditField           matlab.ui.control.EditField
        ConfignameLabel               matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        SAVEButton                    matlab.ui.control.Button
        CalibratedmarkersButton       matlab.ui.control.Button
        VirtualmarkersreconstructionmethodButton  matlab.ui.control.Button
        UpdateSegmentsButton          matlab.ui.control.Button
        INAIL_2                       matlab.ui.control.Image
        INAIL                         matlab.ui.control.Image
        UNIPD_2                       matlab.ui.control.Image
        LeftProsthAlignmentPanel      matlab.ui.container.Panel
        SAPEditField_2                matlab.ui.control.NumericEditField
        SAPEditField_2Label           matlab.ui.control.Label
        TAPcmEditField_2              matlab.ui.control.NumericEditField
        TAPcmEditField_2Label         matlab.ui.control.Label
        PreflexionEditField_2         matlab.ui.control.NumericEditField
        PreflexionEditField_2Label    matlab.ui.control.Label
        IDEditField_2                 matlab.ui.control.EditField
        IDEditField_2Label            matlab.ui.control.Label
        RightProsthAlignmentPanel     matlab.ui.container.Panel
        SAPEditField                  matlab.ui.control.NumericEditField
        SAPEditFieldLabel             matlab.ui.control.Label
        TAPcmEditField                matlab.ui.control.NumericEditField
        TAPcmEditFieldLabel           matlab.ui.control.Label
        PreflexionEditField           matlab.ui.control.NumericEditField
        PreflexionEditFieldLabel      matlab.ui.control.Label
        IDEditField                   matlab.ui.control.EditField
        IDEditFieldLabel              matlab.ui.control.Label
        LeftProsthKneeSet             matlab.ui.container.ButtonGroup
        Image3_2                      matlab.ui.control.Image
        DOFmechanicalkneeButton_2     matlab.ui.control.RadioButton
        RightProsthKneeSet            matlab.ui.container.ButtonGroup
        Image3                        matlab.ui.control.Image
        DOFmechanicalkneeButton       matlab.ui.control.RadioButton
        RightRPFset                   matlab.ui.container.ButtonGroup
        RightCATEditField             matlab.ui.control.EditField
        CATEditFieldLabel             matlab.ui.control.Label
        RightNameEditField            matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        CSfoot_starClamp_2            matlab.ui.control.Image
        CCfoot_srdClamp_2             matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        RightJFootButton              matlab.ui.control.RadioButton
        RightCFootwsquareclampButton  matlab.ui.control.RadioButton
        RightCFootwstarclampButton    matlab.ui.control.RadioButton
        LeftLLDropDown                matlab.ui.control.DropDown
        LeftLLAmpLabel                matlab.ui.control.Label
        RightLLDropDown               matlab.ui.control.DropDown
        RightLLAmpLabel               matlab.ui.control.Label
        LeftULDropDown                matlab.ui.control.DropDown
        LeftULAmpLabel                matlab.ui.control.Label
        RightULDropDown               matlab.ui.control.DropDown
        RightULAmpLabel               matlab.ui.control.Label
        LeftRPFset                    matlab.ui.container.ButtonGroup
        LeftCATEditField              matlab.ui.control.EditField
        CATEditField_2Label           matlab.ui.control.Label
        LeftNameEditField             matlab.ui.control.EditField
        NameEditField_2Label          matlab.ui.control.Label
        CSfoot_starClamp              matlab.ui.control.Image
        CCfoot_srdClamp               matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        LeftJFootButton               matlab.ui.control.RadioButton
        LeftCFootwsquareclampButton   matlab.ui.control.RadioButton
        LeftCFootwstarclampButton     matlab.ui.control.RadioButton
        ConfiguratorLabel             matlab.ui.control.Label
        OLYMPIA                       matlab.ui.control.Image
        UNIPD                         matlab.ui.control.Image
        BodysegmentsPanel             matlab.ui.container.Panel
        MassEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel          matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        LowerBodyImg                  matlab.ui.control.Image
        UpperBodyImg                  matlab.ui.control.Image
        SwitchLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        attribute = "_att";
        subject;
        GeneralSeg;
        GeneralJoints;
        UpperLimbSeg;
        UpperLimbJoints;
        RightLLseg;
        RightLLJoints;
        LeftLLseg;
        LeftLLJoints;
        CalObject;
        CalObjectList;
        segments;
        joints;
        landmarks_to_create;
        sbjroot;
        markertemplate; % Description
        markerinfo;
        CSdef;
        KinDef;
        MarkerSet;
        KinematicModel;
        selectedRows; % Description
        LogRecord = cell(0);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainroot, xmlchoice, jsonchoice, task)
            arguments
                app;
                mainroot (1,1) string = "";
                xmlchoice (1,1) logical = 1;
                jsonchoice (1,1) logical = 1;
                task (1,1) string = "walking";
            end

            for i = 1: length(app.TaskSelectionButtonGroup.Buttons)
                if strcmpi(app.TaskSelectionButtonGroup.Buttons(i).Text, task)
                    app.TaskSelectionButtonGroup.Buttons(i).Value = 1;
                    app.LeftRPFset.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.RightProsthAlignmentPanel.Visible = "off";
                else
                    app.TaskSelectionButtonGroup.Buttons(i).Value = 0;
                    app.LeftRPFset.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.RightProsthAlignmentPanel.Visible = "on";
                end
            end
            
            model_list = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), 'Models', 'list.models'), 'FileType', 'xml', 'AttributeSuffix', app.attribute);
            try
                app.CalObjectList = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()),"CalibrationObjects", "XML", "Wands.calobj"), "FileType", "xml", "AttributeSuffix", app.attribute);
            catch
                app.CalObjectList = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "CalibrationObjects", "JSON", "Wands.calobj"), "FileType", "json");
            end
            app.ModelDropDown.Items = [model_list.Model.(strcat("label", app.attribute))]';
            app.ModelDropDown.Value = app.ModelDropDown.Items(1);
            app.CalobjDropDown.Items = ["None"; fieldnames(app.CalObjectList)];
            app.CalobjDropDown.Value = app.CalobjDropDown.Items(1);

            try
                app.SbjPathEditField.Value = mainroot;
                app.sbjroot = mainroot;
                sbjpath = fileparts(app.SbjPathEditField.Value);
                [~, sbjname] = fileparts(sbjpath);
                
                app.subject = readstruct(fullfile(sbjpath, strcat(sbjname, ".subject")), "FileType", "xml", "AttributeSuffix", app.attribute);
                side = {"left", "right"};

                if isfield(app.subject, "amputation")
                    % check if stored amputation are already given
                    limbs = fieldnames(app.subject.amputation);
                    for i = 1:length(limbs)
                        for j = 1:2
                            if strcmp(limbs{i}, "upperlimb") && strcmp(side{j}, "left")
                                app.LeftULDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                            elseif strcmp(limbs{i}, "upperlimb") && strcmp(side{j}, "right")
                                app.RightULDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                            elseif strcmp(limbs{i}, "lowerlimb") && strcmp(side{j}, "left")
                                app.LeftLLDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                                LeftLLDropDownValueChanged(app);
                            else % strcmp(limbs{i}, "lowerlimb") && strcmp(side{j}, "right")
                                app.RightLLDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                                RightLLDropDownValueChanged(app)
                            end

                        end
                    end
                    
                    SwitchValueChanged(app)

                else
                    app.LeftLLDropDown.Value = "none";
                    app.LeftULDropDown.Value = "none";
                    app.RightLLDropDown.Value = "none";
                    app.RightULDropDown.Value = "none";

                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "off";

                    app.RightProsthAlignmentPanel.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "off";
                end
            catch
                if ~strcmp(mainroot, "")
                    app.SbjPathEditField.Value = mainroot;
                    app.sbjroot = mainroot;
                end
                app.LeftLLDropDown.Value = "none";
                app.LeftULDropDown.Value = "none";
                app.RightLLDropDown.Value = "none";
                app.RightULDropDown.Value = "none";

                app.LeftProsthAlignmentPanel.Visible = "off";
                app.LeftProsthKneeSet.Visible = "off";
                app.LeftRPFset.Visible = "off";

                app.RightProsthAlignmentPanel.Visible = "off";
                app.RightProsthKneeSet.Visible = "off";
                app.RightRPFset.Visible = "off";
                %msg = msgbox("Please select or create a Subject folder to save the config file!", "No subject configuration file was found", "warn", "modal");
                %uiwait(msg);
            end
            
            app.xmlCheckBox.Value = xmlchoice;
            app.jsonCheckBox.Value = jsonchoice;

            % DEPRECATED IN VERSION 1.1 - All informations are now in one *.MarkerSet file: app.markerinfo = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.markerinfo"), "FileType", "xml", "AttributeSuffix", "");
            % app.coordtemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.coord"), "FileType", "xml", "AttributeSuffix", "");
            % app.kintemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Sequences.kinematics"), "FileType", "xml", "AttributeSuffix", "");
            
            % app.landmarks_to_create = repmat(struct('labels', '', 'method', '', 'points', {}), [1, 2]);
            % app.landmarks_to_create(1).labels = "LHJC";
            % app.landmarks_to_create(1).method = "Bell";
            % app.landmarks_to_create(1).points = ["LASIS", "RASIS", "LPSIS", "RPSIS"];
            % 
            % app.landmarks_to_create(2).labels = "RHJC";
            % app.landmarks_to_create(2).method = "Bell";
            % app.landmarks_to_create(2).points = ["LASIS", "RASIS", "LPSIS", "RPSIS"];

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
                case "Full body"
                    app.LeftLLDropDown.Visible = "on";
                    app.LeftLLAmpLabel.Visible = "on";
                    app.RightLLDropDown.Visible = "on";
                    app.RightLLAmpLabel.Visible = "on";
                    app.LeftULDropDown.Visible = "on";
                    app.LeftULAmpLabel.Visible = "on";
                    app.RightULDropDown.Visible = "on";
                    app.RightULAmpLabel.Visible = "on";
                    app.UpperBodyImg.Visible = "on";
                    app.LowerBodyImg.Visible = "on";
                    % app.GeneralSeg = {"Head", "Trunk", "Pelvis"};
                    % app.GeneralJoints = {"Cervical", "Back"};
                    % app.UpperLimbSeg = {"LeftArm", "LeftForearm", "RightArm", "RightForearm"};
                    % app.UpperLimbJoints = {"LeftShoulder", "LeftElbow", "RightShoulder", "RightElbow"};
                case "Lower body"
                    app.LeftLLDropDown.Visible = "on";
                    app.LeftLLAmpLabel.Visible = "on";
                    app.RightLLDropDown.Visible = "on";
                    app.RightLLAmpLabel.Visible = "on";
                    app.LeftULDropDown.Visible = "off";
                    app.LeftULAmpLabel.Visible = "off";
                    app.RightULDropDown.Visible = "off";
                    app.RightULAmpLabel.Visible = "off";
                    app.UpperBodyImg.Visible = "off";
                    app.LowerBodyImg.Visible = "on";
                    % app.GeneralSeg = {"Pelvis"};
                    % app.GeneralJoints = {};
                    % app.UpperLimbSeg = {};
                    % app.UpperLimbJoints = {};
            end
        end

        % Value changed function: RightLLDropDown
        function RightLLDropDownValueChanged(app, event)
            switch app.RightLLDropDown.Value
                case "none"
                    app.RightProsthAlignmentPanel.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    % app.RightLLseg = {"rthigh", "rshank", "rfoot"};
                    % app.RightLLJoints = {"rh", "rk", "ra"};
                case "TT"
                    %panels appear but no knee
                    app.RightProsthAlignmentPanel.Visible = "on";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "on";
                    app.RightJFootButton.Value = 1;
                    app.RightCFootwsquareclampButton.Enable = "off";
                    app.RightCFootwstarclampButton.Enable = "off";
                    % app.RightLLseg = {"rthigh", "rsocket", "rfootclamp_TT", "rprostFoot_prox", "rprostFoot_dist", "rhjc_k", "rgt_k"}; %"rprostFoot_dist", 
                    % app.RightLLJoints = {"rh", "rktt", "rpa"};
                case "TF"
                    %all panels appear"
                    app.RightProsthAlignmentPanel.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.RightCFootwsquareclampButton.Enable = "on";
                    app.RightCFootwstarclampButton.Enable = "on";
                    % app.RightLLseg = {"rsocket", "rsocketclamp", "rprostKnee_prox", "rprostKnee_dist", "rfootclamp_TF", "rprostFoot_prox", "rprostFoot_dist", "rhjc_k", "rgt_k"};
                    % app.RightLLJoints = {"rhtf", "rpk", "rpa"};
            end
        end

        % Value changed function: LeftLLDropDown
        function LeftLLDropDownValueChanged(app, event)
            switch app.LeftLLDropDown.Value
                case "none"
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "off";
                    % app.LeftLLseg = {"lthigh", "lshank", "lfoot"};
                    % app.LeftLLJoints = {"lh", "lk", "la"};
                case "TT"
                    %panels appear but no knee
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "on";
                    app.LeftJFootButton.Value = 1;
                    app.LeftCFootwsquareclampButton.Enable = "off";
                    app.LeftCFootwstarclampButton.Enable = "off";
                    % app.LeftLLseg = {"lsocket", "lfootclamp_TT", "lprostFoot_prox", "lprostFoot_dist", "lhjc_k", "lgt_k"}; %"lprostFoot_dist", 
                    % app.LeftLLJoints = {"lh", "lktt", "lpa"};
                case "TF"
                    %all panels appear"
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.LeftRPFset.Visible = "on";
                    app.LeftCFootwsquareclampButton.Enable = "on";
                    app.LeftCFootwstarclampButton.Enable = "on";
                    % app.LeftLLseg = {"lsocket", "lsocketclamp", "lprostKnee_prox", "lprostKnee_dist", "lfootclamp_TF", "lprostFoot_prox", "lprostFoot_dist", "lhjc_k", "lgt_k"};
                    % app.LeftLLJoints = {"lhtf", "lpk", "lpa"};
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if or(strcmp(app.ConfignameEditField.Value, ""), strcmp(app.ConfignameEditField.Value, ''))
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: The configuration must have a name.']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: File not saved.']};
                app.LogTextArea.Value = app.LogRecord;                
                %wrn = warndlg("The configuration must have a name", "No config name", "modal");
                %uiwait(wrn);
                return;
            else
                if isfile(fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, ".configuration")))
                    answer = questdlg({"A configuration file with same name already exists,", "do you want to overwrite that?"}, "Config exists", "Yes", "No", "No");
                    switch answer
                        case "Yes"
                            % do nothing
                        case "No"
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: File not saved (chose another name for the file).']};
                            app.LogTextArea.Value = app.LogRecord;

                            %wrn = warndlg("Choose another name", "Choose Config name", "modal");
                            %uiwait(wrn);
                            return;
                    end
                end
            end

            % DEPRECATED WITH VERSION 1.1: config.subject = app.subject;
            config.mass.value = app.MassEditField.Value;
            config.mass.unit = "kg";

            switch app.RightLLDropDown.Value
                case "TF"
                    %Right TF
                    config.medical_device.right.type = "running specific prosthesis";
                    
                    %alignment
                    config.medical_device.right.alignment.ID = app.IDEditField.Value;
                    config.medical_device.right.alignment.preflexion_angle.unit = "deg";
                    config.medical_device.right.alignment.preflexion_angle.value = app.PreflexionEditField.Value;
                    config.medical_device.right.alignment.TAP.unit = "cm";
                    config.medical_device.right.alignment.TAP.value = app.TAPcmEditField.Value;
                    config.medical_device.right.alignment.SAP.unit = "cm";
                    config.medical_device.right.alignment.SAP.value = app.SAPEditField.Value;

                    % prosthetic knee
                    switch app.RightProsthKneeSet.SelectedObject
                        case "1-DOF mechanical knee"
                            config.medical_device.right.knee = "Ottobock3S80";
                    end

                    % prosthetic foot
                    config.medical_device.right.foot.name = app.RightNameEditField.Value;
                    config.medical_device.right.foot.cat = app.RightCATEditField.Value;

                case "TT"
                    %Right TT
                    config.medical_device.right.type = "running specific prosthesis";
                    
                    %alignment
                    config.medical_device.right.alignment.ID = app.IDEditField.Value;
                    config.medical_device.right.alignment.preflexion_angle.unit = "deg";
                    config.medical_device.right.alignment.preflexion_angle.value = app.PreflexionEditField.Value;
                    config.medical_device.right.alignment.TAP.unit = "cm";
                    config.medical_device.right.alignment.TAP.value = app.TAPcmEditField.Value;
                    config.medical_device.right.alignment.SAP.unit = "cm";
                    config.medical_device.right.alignment.SAP.value = app.SAPEditField.Value;

                    % prosthetic foot
                    config.medical_device.right.foot.name = app.RightNameEditField.Value;
                    config.medical_device.right.foot.cat = app.RightCATEditField.Value;

                case "none"
                    % Right sound limb - NO MEDICAL DEVICE STORED IN THE
                    % CONFIG FILE
            end
            switch app.LeftLLDropDown.Value
                case "TF"
                    %Left TF
                    config.medical_device.left.type = "running specific prosthesis";
                    
                    %alignment
                    config.medical_device.left.alignment.ID = app.IDEditField_2.Value;
                    config.medical_device.left.alignment.preflexion_angle.unit = "deg";
                    config.medical_device.left.alignment.preflexion_angle.value = app.PreflexionEditField_2.Value;
                    config.medical_device.left.alignment.TAP.unit = "cm";
                    config.medical_device.left.alignment.TAP.value = app.TAPcmEditField_2.Value;
                    config.medical_device.left.alignment.SAP.unit = "cm";
                    config.medical_device.left.alignment.SAP.value = app.SAPEditField_2.Value;

                    % prosthetic knee
                    switch app.LeftProsthKneeSet.SelectedObject
                        case "1-DOF mechanical knee"
                            config.medical_device.left.knee = "Ottobock3S80";
                    end

                    % prosthetic foot
                    config.medical_device.left.foot.name = app.LeftNameEditField.Value;
                    config.medical_device.left.foot.cat = app.LeftCATEditField.Value;
                case "TT"
                    %Left TT
                    config.medical_device.left.type = "running specific prosthesis";

                    %alignment
                    config.medical_device.left.alignment.ID = app.IDEditField_2.Value;
                    config.medical_device.left.alignment.preflexion_angle.unit = "deg";
                    config.medical_device.left.alignment.preflexion_angle.value = app.PreflexionEditField_2.Value;
                    config.medical_device.left.alignment.TAP.unit = "cm";
                    config.medical_device.left.alignment.TAP.value = app.TAPcmEditField_2.Value;
                    config.medical_device.left.alignment.SAP.unit = "cm";
                    config.medical_device.left.alignment.SAP.value = app.SAPEditField_2.Value;

                    % prosthetic foot
                    config.medical_device.left.foot.name = app.LeftNameEditField.Value;
                    config.medical_device.left.foot.cat = app.LeftCATEditField.Value;
                case "none"
                    %Left sound limb - NO MEDICAL DEVICE STORED IN THE
                    % CONFIG FILE
            end
            
            % CONFIG FILE
            %config_json = config;
            %config_json.MarkerSet = app.MarkerSet;
            %config_json.KinematicModel = app.KinematicModel;
            config.MarkerSet = app.MarkerSet;
            config.KinematicModel = app.KinematicModel;
            
            %    
            % field = fieldnames(app.MarkerSet);
            % for i = 1:numel(field)
            %     config.MarkerSet.(field{i}).Attributes = app.MarkerSet.(field{i});
            % end
            % clear field;
            % %
            % field = fieldnames(app.KinematicModel);
            % for i = 1:numel(field)
            %     if strcmp(field{i}, "Segment")
            %         subfields = fieldnames(app.KinematicModel.(field{i}));
            %         select = logical(matches(subfields, "CoordSys"));
            %         config.KinematicModel.(field{i}).Attributes = app.KinematicModel.(field{i});
            %         config.KinematicModel.(field{i}).Attributes = rmfield(config.KinematicModel.(field{i}).Attributes, "CoordSys");
            %         config.KinematicModel.(field{i}).Value = app.KinematicModel.(field{i});
            %         config.KinematicModel.(field{i}).Value = rmfield(config.KinematicModel.(field{i}).Value, subfields(~select));
            %     else
            %         config.KinematicModel.(field{i}).Attributes = app.KinematicModel.(field{i});
            %     end
            % end
            % 
            
            if app.xmlCheckBox.Value && ~app.jsonCheckBox.Value
                writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
            elseif ~app.xmlCheckBox.Value && app.jsonCheckBox.Value
                writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
                temp = readstruct(fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", "", "FileType", "xml");
                delete(fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")));
                writestruct(temp, fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_json.configuration")), "FileType", "json");
            elseif app.xmlCheckBox.Value && app.jsonCheckBox.Value
                writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
                temp = readstruct(fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", "", "FileType", "xml");
                writestruct(temp, fullfile(app.Path2saveEditField.Value, strcat(app.ConfignameEditField.Value, "_json.configuration")), "FileType", "json");
            else
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Select at least one file format between XML or JSON.']};
                app.LogTextArea.Value = app.LogRecord;

                % err = errordlg("Select at least one file format between XML or JSON.", "Select a file format!", "modal");
                % uiwait(err);
            end

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Done: Configuration saved!']};
            app.LogTextArea.Value = app.LogRecord;

            % msg = msgbox("Configuration saved!", "modal");
            % uiwait(msg);
        end

        % Button pushed function: CalibratedmarkersButton
        function CalibratedmarkersButtonPushed(app, event)
            tocalibrate = [app.MarkerSet.Marker(and(~matches([app.MarkerSet.Marker.(strcat("type", app.attribute))], "V"), [app.MarkerSet.Marker.(strcat("calibrated", app.attribute))] == 1)).(strcat("label", app.attribute))];
            app.MarkerSet.CalibratedMarkerDef = repmat(struct("label", [], "wand", [], "source", []), 1, length(tocalibrate));
            for i = 1:length(tocalibrate)
                app.MarkerSet.CalibratedMarkerDef(i).(strcat("label", app.attribute)) = tocalibrate(i);
                if strcmp(app.CalobjDropDown.Value, "None")
                    app.MarkerSet.CalibratedMarkerDef(i).(strcat("wand", app.attribute)) = "";
                else
                    app.MarkerSet.CalibratedMarkerDef(i).(strcat("wand", app.attribute)) = string(app.CalobjDropDown.Value);
                end
                app.MarkerSet.CalibratedMarkerDef(i).(strcat("source", app.attribute)) = "";
            end

            app.SAVEButton.Enable = "on";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Done: Markers to be calibrated exported.']};
            app.LogTextArea.Value = app.LogRecord;

            % msg = msgbox("Markers to calibrate exported", "Done!", "modal");
            % uiwait(msg)
        end

        % Button pushed function: VirtualmarkersreconstructionmethodButton
        function VirtualmarkersreconstructionmethodButtonPushed(app, event)
            fig = uifigure("Name", "Check virtual markers that have to be calculated");
            fig.Position = [250 250 1000 510];

            % Create ListBoxLabel
            VirtualMarkerListBoxLabel = uilabel(fig);
            VirtualMarkerListBoxLabel.HorizontalAlignment = 'left';
            VirtualMarkerListBoxLabel.Position = [10 470 100 30];
            VirtualMarkerListBoxLabel.Text = {'List of virtual', 'markers:'};
            VirtualMarkerListBoxLabel.FontWeight = 'bold';

            % Create ListBox
            VirtualMarkerListBox = uilistbox(fig);
            VirtualMarkerListBox.Position = [10 50 100 410];
            VirtualMarkerListBox.Items = [app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("type", app.attribute))], "V")).(strcat("label", app.attribute))];
            VirtualMarkerListBox.Multiselect = "off";

            % Create ListBoxLabel
            ReconstructionMethodLabel = uilabel(fig);
            ReconstructionMethodLabel.HorizontalAlignment = 'left';
            ReconstructionMethodLabel.Position = [120 470 150 30];
            ReconstructionMethodLabel.Text = {'Select the reconstruction', 'method'};
            ReconstructionMethodLabel.FontWeight = 'bold';
            
            % Create ListBox
            ReconstructionMethodListBox = uilistbox(fig);
            ReconstructionMethodListBox.Position = [120 310 150 150];
            ReconstructionMethodListBox.Items = {'Bell', 'Centroid'};
            ReconstructionMethodListBox.Multiselect = "off";

            % Create ListBoxLabel
            CompleteMarkerListBoxLabel = uilabel(fig);
            CompleteMarkerListBoxLabel.HorizontalAlignment = 'left';
            CompleteMarkerListBoxLabel.VerticalAlignment = 'top';
            CompleteMarkerListBoxLabel.Position = [280 470 100 30];
            CompleteMarkerListBoxLabel.Text = 'Markerset labels:';
            CompleteMarkerListBoxLabel.FontWeight = 'bold';

            % Create ListBox
            CompleteMarkerListBox = uilistbox(fig);
            CompleteMarkerListBox.Position = [280 50 100 410];
            CompleteMarkerListBox.Items = [app.MarkerSet.Marker.(strcat("label", app.attribute))];
            CompleteMarkerListBox.Multiselect = "on";

            % Create the table
            VirtualMarkerTable = uitable(fig);
            VirtualMarkerTable.ColumnName = {'Label', 'Method', 'Source'};
            VirtualMarkerTable.ColumnEditable = [false, true, true];
            VirtualMarkerTable.Position = [500 50 490 410]; % Specify position and size of the table
            VirtualMarkerTable.Data = [cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("label", app.attribute))])', ...
                                       cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("method", app.attribute))])', ...
                                       cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("source", app.attribute))])'];
            VirtualMarkerTable.Multiselect = "on";
            VirtualMarkerTable.CellSelectionCallback = @(src, event) SelectedRow(app, event);

            uicontrol('Parent', fig, 'Position', [390 280 100 20], 'String', 'Add >>',...
                'Callback', @(src, in) AddButtonPushed(app, {VirtualMarkerListBox, ReconstructionMethodListBox, CompleteMarkerListBox, VirtualMarkerTable}),...
                'FontSize', 9, 'FontWeight', 'bold');

            uicontrol('Parent', fig, 'Position', [390 230 100 20], 'String', '<< Remove',...
                'Callback', @(src, in) RemoveButtonPushed(app, VirtualMarkerTable),...
                'FontSize', 9, 'FontWeight', 'bold');

            uicontrol('Parent', fig, 'Position', [480 10 100 25], 'String', 'Save and Close',...
                'Callback', @(src, in) SaveCloseButtonPushed(app, {VirtualMarkerTable, fig}),...
                'BackgroundColor', [0.7373 0.8706 0.5647], 'FontSize', 9, 'FontWeight', 'bold');

            function SelectedRow(app, event)
                app.selectedRows = unique(event.Indices(:,1)); 
            end

            function AddButtonPushed(~, in) %in = {VirtualMarkerListBox, ReconstructionMethodListBox, CompleteMarkerListBox, VirtualMarkerTable}
                in{4}.Data = [in{4}.Data; [cellstr(in{1}.Value) cellstr(in{2}.Value) cellstr(strjoin(in{3}.Value, ","))]];
            end

            function RemoveButtonPushed(app, in) %in = VirtualMarkerTable
                in.Data(app.selectedRows, :)=[];
            end

            function SaveCloseButtonPushed(app, in) %in = {VirtualMarkerTable, fig}
                for i = 1:size(in{1}.Data, 1)
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("label", app.attribute)) = in{1}.Data{i, 1};
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("method", app.attribute)) = in{1}.Data{i, 2};
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("source", app.attribute)) = in{1}.Data{i, 3};
                end

                delete(in{2});
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Methods for virtual markers methods reconstruction exported.']};
                app.LogTextArea.Value = app.LogRecord;
            end
        end

        % Button pushed function: UpdateSegmentsButton
        function UpdateSegmentsButtonPushed(app, event)
            RightLLDropDownValueChanged(app)
            LeftLLDropDownValueChanged(app)

            if app.xmlCheckBox.Value
                app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", app.attribute);
                app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".kinematics")), "FileType", "xml", "AttributeSuffix", app.attribute);
                app.CSdef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".coord")), "FileType", "xml", "AttributeSuffix", app.attribute);
            else
                app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "json");
                app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".kinematics")), "FileType", "json");
                app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".coord")), "FileType", "json");
            end
            
            %%% --- DO EVERTYTHING AS IF FULL BODY IS ASKED --- %%%
            app.GeneralSeg = unique([app.CSdef.General.Segment.(strcat("label", app.attribute))], "stable");
            app.GeneralJoints = unique([app.KinDef.General.Joint.(strcat("label", app.attribute))], "stable");
            app.UpperLimbSeg = unique([app.CSdef.Unaffected_upper.Segment.(strcat("label", app.attribute))], "stable");%unique([app.markertemplate.Unaffected_upper.Marker.segment], "stable");
            app.UpperLimbJoints = unique([app.KinDef.Unaffected_upper.Joint.(strcat("label", app.attribute))], "stable");
            %%%%%%%%%%%%
            switch app.RightLLDropDown.Value
                case "none"
                    app.RightLLseg = unique([app.CSdef.Unaffected_lower.Segment(strcmp([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))], "Right")).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Unaffected_lower.Joint(strcmp([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Right")).(strcat("label", app.attribute))], "stable");
                case "TF"
                    app.RightLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Right"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                case "TT"
                    app.RightLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Right"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
            end
            %%%%%%%%%%%%
            switch app.LeftLLDropDown.Value
                case "none"
                    app.LeftLLseg = unique([app.CSdef.Unaffected_lower.Segment(strcmp([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))], "Left")).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Unaffected_lower.Joint(strcmp([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Left")).(strcat("label", app.attribute))], "stable");
                case "TF"
                    app.LeftLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Left"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                case "TT"
                    app.LeftLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Left"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
            end
            %%% --- DELETE UPPER BODY IF LOWER BODY IS SELECTED --- %
            if strcmp(app.Switch.Value, "Lower body")
                app.GeneralSeg(1:2) = [];
                app.GeneralJoints = [];
                app.UpperLimbSeg = [];
                app.UpperLimbJoints = [];
            end            
        
            app.segments = [app.GeneralSeg, app.UpperLimbSeg, app.RightLLseg, app.LeftLLseg];
            app.joints = [app.GeneralJoints, app.UpperLimbJoints, app.RightLLJoints, app.LeftLLJoints];

            %%% --- CREATE MARKER LIST FROM TEMPLATE AND SETTINGS --- %%%
            app.MarkerSet.Marker = app.markertemplate.Marker(matches([app.markertemplate.Marker.(strcat("segment", app.attribute))], app.segments));
            %%% --- SELECT TF/TT SPECIFIC MARKERS --- %%%
            if matches(app.RightLLDropDown.Value, "TF")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Right"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 3)) = [];
            elseif matches(app.RightLLDropDown.Value, "TT")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Right"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 2)) = [];
            end
            if matches(app.LeftLLDropDown.Value, "TF")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Left"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 3)) = [];
            elseif matches(app.LeftLLDropDown.Value, "TT")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Left"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 2)) = [];
            end

            if app.RightCFootwstarclampButton.Value
                app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("label", app.attribute))], ["RCLAL", "RCLPL", "RCLAM", "RCLPM"])) = [];
            end
            if app.LeftCFootwstarclampButton.Value
                app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("label", app.attribute))], ["LCLAL", "LCLPL", "LCLAM", "LCLPM"])) = [];
            end

            %%% CREATE VIRTUAL MARKER LIST FROM TEMPLATE AND SETTINGS %%%
            app.MarkerSet.VirtualMarkerDef = app.markertemplate.VirtualMarkerDef(matches([app.markertemplate.VirtualMarkerDef.(strcat("label", app.attribute))], [app.MarkerSet.Marker.(strcat("label", app.attribute))]));
            if or(app.RightCFootwstarclampButton.Value, app.LeftCFootwstarclampButton.Value)
                app.MarkerSet.VirtualMarkerDef(matches([app.MarkerSet.VirtualMarkerDef.(strcat("label", app.attribute))], ["RCLP", "RCLA", "RCLL", "RCLM", "LCLP", "LCLA", "LCLL", "LCLM"])) = [];
            end

            %%% -- CREATE KINEMATIC MODEL FROM TEMPLATE AND SETTINGS -- %%%
            % ------- SELECT COORD SYS FROM TEMPLATE AND SETTINGS ------- %
            tempstruct = app.CSdef;
            fields = fieldnames(tempstruct);
            tempstruct.Segment = tempstruct.(fields{1}).Segment;
            for i = 2:numel(fields)
                tempstruct.Segment = [tempstruct.Segment tempstruct.(fields{i}).Segment];
            end
            app.KinematicModel.Segment = tempstruct.Segment;
            %%% --- SELECT TF/TT SPECIFIC JOINTS --- %%%
            switch app.RightLLDropDown.Value
                case "none"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg);
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2));
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbSeg)
                selectr = selectr + and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.UpperLimbSeg)); % correct to not delete those pertaining to the upper body (if considered)
            end
            
            switch app.LeftLLDropDown.Value
                case "none"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg);
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2));
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbSeg)
                selectl = selectl + and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.UpperLimbSeg)); % correct to not delete those pertaining to the upper body (if considered)
            end
            if strcmp(app.Switch.Value, "Full body")
                select = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.GeneralSeg) + ...
                        + selectr + selectl;
            else
                select = selectr+selectl;
            end
            app.KinematicModel.Segment(~logical(select)) = [];

            % -------- SELECT JOINTS FROM TEMPLATE AND SETTINGS -------- %
            tempstruct = app.KinDef;
            fields = fieldnames(tempstruct);
            tempstruct.Joint = tempstruct.(fields{1}).Joint;
            for i = 2:numel(fields)
                if matches(fields{i}, "KinematicChain")
                    continue;
                else
                    tempstruct.Joint = [tempstruct.Joint tempstruct.(fields{i}).Joint];
                end
            end
            app.KinematicModel.Joint = tempstruct.Joint;
            %%% --- SELECT TF/TT SPECIFIC JOINTS --- %%%
            switch app.RightLLDropDown.Value
                case "none"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbJoints)
                selectr = selectr + and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.UpperLimbJoints)); % correct to not delete those pertaining to the upper body (if considered)
            end
                             
            
            switch app.LeftLLDropDown.Value
                case "none"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbJoints)
                selectl = selectl + and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.UpperLimbJoints)); % correct to not delete those pertaining to the upper body (if considered)
            end
            if strcmp(app.Switch.Value, "Full body")
                select = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.GeneralJoints) + ...
                        + selectr + selectl;
            else
                select = selectr+selectl;
            end
            app.KinematicModel.Joint(~logical(select)) = [];
            
            % ---- SELECT KINEMATIC CHAIN FROM TEMPLATE AND SETTINGS ---- %
            switch app.RightLLDropDown.Value
                case "none"
                    temp = "unaffected_lower";
                case "TF"
                    temp = "tf_lower";
                case "TT"
                    temp = "tt_lower";
            end
            app.KinematicModel.KinematicChain(1) = app.KinDef.KinematicChain.(temp)(matches([app.KinDef.KinematicChain.(temp).(strcat("group", app.attribute))], "Right"));
            switch app.LeftLLDropDown.Value
                case "none"
                    temp = "unaffected_lower";
                case "TF"
                    temp = "tf_lower";
                case "TT"
                    temp = "tt_lower";
            end
            app.KinematicModel.KinematicChain(2) = app.KinDef.KinematicChain.(temp)(matches([app.KinDef.KinematicChain.(temp).(strcat("group", app.attribute))], "Left"));

            % DEPRECATED IN VERSION 1.1:
            % app.landmarks_to_reconstruct = [];
            % for i = 1:length(app.joints)
            %     jointcenters = ["RKJC", "RAJC", "LKJC", "LAJC"];
            %     points4centers = [{"RFLE", "RFME"}; {"RLM", "RMM"}; {"LFLE", "LFME"}; {"LLM", "LMM"}];
            %     if ismember(app.joints(i), ["rk", "ra", "lk", "la"])
            %         if isempty(app.landmarks_to_create)
            %             app.landmarks_to_create = struct('labels', '', 'method', '', 'points', {});
            %             app.landmarks_to_create.labels = jointcenters(["rk", "ra", "lk", "la"] == app.joints);
            %             app.landmarks_to_create.method = "midpoint";
            %             app.landmarks_to_create.points = string(points4centers(["rk", "ra", "lk", "la"] == app.joints, :));
            %         else
            %             keys = fieldnames(app.landmarks_to_create);
            %             values = {jointcenters(["rk", "ra", "lk", "la"] == app.joints(i)), "midpoint", string(points4centers(["rk", "ra", "lk", "la"] == app.joints(i), :))};
            %             temp = cell2struct(values, keys, 2);
            %             app.landmarks_to_create = [app.landmarks_to_create, temp];
            %         end
            %     end
            % end
            
            app.VirtualmarkersreconstructionmethodButton.Enable = "on";
            app.CalibratedmarkersButton.Enable = "on";
            app.Path2saveEditField.Enable = "on";
            app.SelectpathtostoretheconfigfileButton.Enable = "on";
            app.Path2saveEditField.Value = app.sbjroot;
            app.CloseButton.Enable = "on";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segments created as configurated!']};
            app.LogTextArea.Value = app.LogRecord;

            % msg = msgbox("Segments created as configurated!", "modal");
            % uiwait(msg);
        end

        % Button pushed function: ConfirmModelButton
        function ConfirmModelButtonPushed(app, event)
            if app.xmlCheckBox.Value
                app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", "");
            else
                app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "json");
            end
            app.ConfirmModelButton.Enable = "off";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', app.ModelDropDown.Value, ' model selected.']};
            app.LogTextArea.Value = app.LogRecord;
        end

        % Button pushed function: ConfirmCalObjButton
        function ConfirmCalObjButtonPushed(app, event)
            if strcmp(app.CalobjDropDown.Value, "None")
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No calibration object selected!']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - To select a calibration object, use the DropDown menu and "Confirm" the selection.']};
                app.LogTextArea.Value = app.LogRecord;
               %  msg = msgbox(["To select a calibration object, use the", "DropDown menu and 'Confirm' the selection."], "No calibration object selected!", "warn", "modal");
               % uiwait(msg);
            else
                app.CalObject = app.CalObjectList.(app.CalobjDropDown.Value);
            end
            app.ConfirmCalObjButton.Enable = "off";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', app.CalobjDropDown.Value, ' chosen as calibration object.']};
            app.LogTextArea.Value = app.LogRecord;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.ConfirmModelButton.Enable = "on";
        end

        % Value changed function: CalobjDropDown
        function CalobjDropDownValueChanged(app, event)
            app.ConfirmCalObjButton.Enable = "on";
        end

        % Button pushed function: SelectpathtostoretheconfigfileButton
        function SelectpathtostoretheconfigfileButtonPushed(app, event)
            app.Path2saveEditField.Value = uigetdir();
            app.SAVEButton.Enable = "on";
        end

        % Button pushed function: SbjBrowseButton
        function SbjBrowseButtonPushed(app, event)
            selectedfolder = uigetdir();
            try
                [path, folder] = fileparts(selectedfolder);
            catch
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Subject folder not selected.']};
                app.LogTextArea.Value = app.LogRecord;
                % err = warndlg("No folder selected", "Subject folder not selected");
                % uiwait(err)
                return
            end
            app.SbjPathEditField.Value = fullfile(path, folder);
            app.sbjroot = app.SbjPathEditField.Value;
            app.Path2saveEditField.Value = app.SbjPathEditField.Value;

            % app.LeftLLDropDown.Value = "none";
            % app.LeftULDropDown.Value = "none";
            % app.RightLLDropDown.Value = "none";
            % app.RightULDropDown.Value = "none";
            % 
            % app.LeftProsthAlignmentPanel.Visible = "off";
            % app.LeftProsthKneeSet.Visible = "off";
            % app.LeftRPFset.Visible = "off";
            % 
            % app.RightProsthAlignmentPanel.Visible = "off";
            % app.RightProsthKneeSet.Visible = "off";
            % app.RightRPFset.Visible = "off";

            %app.markertemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.MarkerSet"), "FileType", "xml", "AttributeSuffix", "");
            RightLLDropDownValueChanged(app)
            LeftLLDropDownValueChanged(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogRecord = cell(0);
            app.LogTextArea.Value = cell(0,1);
        end

        % Button pushed function: ExportlogButton
        function ExportlogButtonPushed(app, event)
            if isempty(app.SbjPathEditField.Value)
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No subject folder selected!']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - The log file is exported by default in the subject folder.']};
                app.LogTextArea.Value = app.LogRecord;
            else
                fid = fopen(fullfile(app.SbjPathEditField.Value, ['log_export_', char(datetime('now','Format','yyyy_MM_dd_HH_mm_ss')), '.txt']), 'w');
                for i = 1:length(app.LogTextArea.Value)
                    fprintf(fid, '%s\n', app.LogTextArea.Value{i});
                end
                fclose(fid);
            end
        end

        % Selection changed function: TaskSelectionButtonGroup
        function TaskSelectionButtonGroupSelectionChanged(app, event)
            switch lower(app.TaskSelectionButtonGroup.SelectedObject.Text)
                case "walking"
                    app.LeftRPFset.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.RightProsthAlignmentPanel.Visible = "off";
                case "running"
                    app.LeftRPFset.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.RightProsthAlignmentPanel.Visible = "on";
            end
        end

        % Button pushed function: AddModelButton
        function AddModelButtonPushed(app, event)
            model = ModelCreator(app.MainPathEditField.Value, app.xmlCheckBox.Value, app.jsonCheckBox.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BodysegmentsPanel = uipanel(app.UIFigure);
            app.BodysegmentsPanel.Title = 'Body segments';
            app.SwitchLabel = uilabel(app.BodysegmentsPanel);
            app.SwitchLabel.Visible = 'off';
            app.SwitchLabel.Text = 'Switch';
            app.UpperBodyImg = uiimage(app.BodysegmentsPanel);
            app.UpperBodyImg.ImageSource = 'upper_body.png';
            app.LowerBodyImg = uiimage(app.BodysegmentsPanel);
            app.LowerBodyImg.ImageSource = 'lower_body.png';
            app.Switch = uiswitch(app.BodysegmentsPanel, 'slider');
            app.Switch.Items = {'Full body', 'Lower body'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Full body';
            app.MasskgEditFieldLabel = uilabel(app.BodysegmentsPanel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MassEditField = uieditfield(app.BodysegmentsPanel, 'numeric');
            app.MassEditField.Limits = [0 Inf];
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.ConfiguratorLabel = uilabel(app.UIFigure);
            app.ConfiguratorLabel.Text = 'Configurator';
            app.LeftRPFset = uibuttongroup(app.UIFigure);
            app.LeftRPFset.Title = 'Left Prosthetic Foot';
            app.LeftCFootwstarclampButton = uiradiobutton(app.LeftRPFset);
            app.LeftCFootwstarclampButton.Text = 'C-Foot w/ star clamp';
            app.LeftCFootwstarclampButton.Value = true;
            app.LeftCFootwsquareclampButton = uiradiobutton(app.LeftRPFset);
            app.LeftCFootwsquareclampButton.Text = 'C-Foot w/ square clamp';
            app.LeftJFootButton = uiradiobutton(app.LeftRPFset);
            app.LeftJFootButton.Text = 'J-Foot';
            app.Image2 = uiimage(app.LeftRPFset);
            app.Image2.ImageSource = 'CS-foot_clamp.png';
            app.CCfoot_srdClamp = uiimage(app.LeftRPFset);
            app.CCfoot_srdClamp.ImageSource = 'CC-foot_clamp.png';
            app.CSfoot_starClamp = uiimage(app.LeftRPFset);
            app.CSfoot_starClamp.ImageSource = 'J-foot.png';
            app.NameEditField_2Label = uilabel(app.LeftRPFset);
            app.NameEditField_2Label.Text = 'Name:';
            app.LeftNameEditField = uieditfield(app.LeftRPFset, 'text');
            app.CATEditField_2Label = uilabel(app.LeftRPFset);
            app.CATEditField_2Label.Text = 'CAT:';
            app.LeftCATEditField = uieditfield(app.LeftRPFset, 'text');
            app.RightULAmpLabel = uilabel(app.UIFigure);
            app.RightULAmpLabel.Text = 'Right upper limb amputation';
            app.RightULDropDown = uidropdown(app.UIFigure);
            app.RightULDropDown.Items = {'none', 'TH', 'TR'};
            app.RightULDropDown.Value = 'none';
            app.LeftULAmpLabel = uilabel(app.UIFigure);
            app.LeftULAmpLabel.Tag = 'LeftULlabel';
            app.LeftULAmpLabel.Text = 'Left upper limb amputation';
            app.LeftULDropDown = uidropdown(app.UIFigure);
            app.LeftULDropDown.Items = {'none', 'TH', 'TR'};
            app.LeftULDropDown.Value = 'none';
            app.RightLLAmpLabel = uilabel(app.UIFigure);
            app.RightLLAmpLabel.Text = 'Right lower limb amputation';
            app.RightLLDropDown = uidropdown(app.UIFigure);
            app.RightLLDropDown.Items = {'none', 'TF', 'TT'};
            app.RightLLDropDown.ValueChangedFcn = createCallbackFcn(app, @RightLLDropDownValueChanged, true);
            app.RightLLDropDown.Value = 'none';
            app.LeftLLAmpLabel = uilabel(app.UIFigure);
            app.LeftLLAmpLabel.Text = 'Left lower limb amputation';
            app.LeftLLDropDown = uidropdown(app.UIFigure);
            app.LeftLLDropDown.Items = {'none', 'TF', 'TT'};
            app.LeftLLDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftLLDropDownValueChanged, true);
            app.LeftLLDropDown.Value = 'none';
            app.RightRPFset = uibuttongroup(app.UIFigure);
            app.RightRPFset.Title = 'Right Prosthetic Foot';
            app.RightCFootwstarclampButton = uiradiobutton(app.RightRPFset);
            app.RightCFootwstarclampButton.Text = 'C-Foot w/ star clamp';
            app.RightCFootwstarclampButton.Value = true;
            app.RightCFootwsquareclampButton = uiradiobutton(app.RightRPFset);
            app.RightCFootwsquareclampButton.Text = 'C-Foot w/ square clamp';
            app.RightJFootButton = uiradiobutton(app.RightRPFset);
            app.RightJFootButton.Text = 'J-Foot';
            app.Image2_2 = uiimage(app.RightRPFset);
            app.Image2_2.ImageSource = 'CS-foot_clamp.png';
            app.CCfoot_srdClamp_2 = uiimage(app.RightRPFset);
            app.CCfoot_srdClamp_2.ImageSource = 'CC-foot_clamp.png';
            app.CSfoot_starClamp_2 = uiimage(app.RightRPFset);
            app.CSfoot_starClamp_2.ImageSource = 'J-foot.png';
            app.NameEditFieldLabel = uilabel(app.RightRPFset);
            app.NameEditFieldLabel.Text = 'Name:';
            app.RightNameEditField = uieditfield(app.RightRPFset, 'text');
            app.CATEditFieldLabel = uilabel(app.RightRPFset);
            app.CATEditFieldLabel.Text = 'CAT:';
            app.RightCATEditField = uieditfield(app.RightRPFset, 'text');
            app.RightProsthKneeSet = uibuttongroup(app.UIFigure);
            app.RightProsthKneeSet.Title = 'Right Prosthetic Knee';
            app.DOFmechanicalkneeButton = uiradiobutton(app.RightProsthKneeSet);
            app.DOFmechanicalkneeButton.Text = '1-DOF mechanical knee';
            app.DOFmechanicalkneeButton.Value = true;
            app.Image3 = uiimage(app.RightProsthKneeSet);
            app.Image3.ImageSource = 'knee.png';
            app.LeftProsthKneeSet = uibuttongroup(app.UIFigure);
            app.LeftProsthKneeSet.Title = 'Left Prosthetic Knee';
            app.DOFmechanicalkneeButton_2 = uiradiobutton(app.LeftProsthKneeSet);
            app.DOFmechanicalkneeButton_2.Text = '1-DOF mechanical knee';
            app.DOFmechanicalkneeButton_2.Value = true;
            app.Image3_2 = uiimage(app.LeftProsthKneeSet);
            app.Image3_2.ImageSource = 'knee.png';
            app.RightProsthAlignmentPanel = uipanel(app.UIFigure);
            app.RightProsthAlignmentPanel.Title = 'Right Prosthesis Alignment';
            app.IDEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.RightProsthAlignmentPanel, 'text');
            app.PreflexionEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.PreflexionEditFieldLabel.Text = 'Preflexion (°)';
            app.PreflexionEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.TAPcmEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.TAPcmEditFieldLabel.Text = 'TAP (cm)';
            app.TAPcmEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.SAPEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.SAPEditFieldLabel.Text = 'SAP (°)';
            app.SAPEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.LeftProsthAlignmentPanel = uipanel(app.UIFigure);
            app.LeftProsthAlignmentPanel.Title = 'Left Prosthesis Alignment';
            app.IDEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.IDEditField_2Label.Text = 'ID';
            app.IDEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'text');
            app.PreflexionEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.PreflexionEditField_2Label.Text = 'Preflexion (°)';
            app.PreflexionEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.TAPcmEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.TAPcmEditField_2Label.Text = 'TAP (cm)';
            app.TAPcmEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.SAPEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.SAPEditField_2Label.Text = 'SAP (°)';
            app.SAPEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.jpg';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UpdateSegmentsButton = uibutton(app.Panel, 'push');
            app.UpdateSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSegmentsButtonPushed, true);
            app.UpdateSegmentsButton.Tooltip = {'After having set up the subject configuration using the Body segments panel and possible Right and Left Prosthetic parts panels, click on "Update segments" to let the software pick the relevant needed parts from the model and calibration files to start setting up the *.config file.'};
            app.UpdateSegmentsButton.Text = 'Update segments';
            app.VirtualmarkersreconstructionmethodButton = uibutton(app.Panel, 'push');
            app.VirtualmarkersreconstructionmethodButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualmarkersreconstructionmethodButtonPushed, true);
            app.VirtualmarkersreconstructionmethodButton.Enable = 'off';
            app.VirtualmarkersreconstructionmethodButton.Tooltip = {'Click here to check which virtual markers will be reconstructed and which method is going to be used. You will be able to modify anything you need.'};
            app.VirtualmarkersreconstructionmethodButton.Text = 'Virtual markers reconstruction method';
            app.CalibratedmarkersButton = uibutton(app.Panel, 'push');
            app.CalibratedmarkersButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratedmarkersButtonPushed, true);
            app.CalibratedmarkersButton.Enable = 'off';
            app.CalibratedmarkersButton.Tooltip = {'Click here after having updated the segments to check which markers are going to be calibrated using the calibration object selected in the "calibration object drop-down menu".'};
            app.CalibratedmarkersButton.Text = 'Calibrated markers';
            app.SAVEButton = uibutton(app.Panel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.ConfignameLabel = uilabel(app.Panel);
            app.ConfignameLabel.Text = 'Config name:';
            app.ConfignameEditField = uieditfield(app.Panel, 'text');
            app.ConfignameEditField.Placeholder = 'Type the *.config file name';
            app.Path2saveEditField = uieditfield(app.Panel, 'text');
            app.Path2saveEditField.Placeholder = 'No folder selected.';
            app.SelectpathtostoretheconfigfileButton = uibutton(app.Panel, 'push');
            app.SelectpathtostoretheconfigfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathtostoretheconfigfileButtonPushed, true);
            app.SelectpathtostoretheconfigfileButton.Text = 'Select path to store the *.config file';
            app.xmlCheckBox = uicheckbox(app.Panel);
            app.xmlCheckBox.Text = '*.xml';
            app.xmlCheckBox.Value = true;
            app.jsonCheckBox = uicheckbox(app.Panel);
            app.jsonCheckBox.Text = '*.json';
            app.jsonCheckBox.Value = true;
            app.ConfigurationfileformatLabel = uilabel(app.Panel);
            app.ConfigurationfileformatLabel.Text = 'Configuration file format:';
            app.SelectthebiomechanicalmodeltobeappliedLabel = uilabel(app.Panel);
            app.SelectthebiomechanicalmodeltobeappliedLabel.Text = 'Biomechanical model to be applied:';
            app.ModelDropDown = uidropdown(app.Panel);
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ConfirmModelButton = uibutton(app.Panel, 'push');
            app.ConfirmModelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmModelButtonPushed, true);
            app.ConfirmModelButton.Text = 'Confirm';
            app.AddModelButton = uibutton(app.Panel, 'push');
            app.AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @AddModelButtonPushed, true);
            app.AddModelButton.Enable = 'off';
            app.AddModelButton.Tooltip = {'In next version of the app, this button will activate a new window to  create a new model to be listed in the "select model" drop-down menu.'};
            app.AddModelButton.Text = 'Add';
            app.SelectthebiomechanicalmodeltobeappliedLabel_2 = uilabel(app.Panel);
            app.SelectthebiomechanicalmodeltobeappliedLabel_2.Text = 'Calibration object (if any):';
            app.CalobjDropDown = uidropdown(app.Panel);
            app.CalobjDropDown.ValueChangedFcn = createCallbackFcn(app, @CalobjDropDownValueChanged, true);
            app.AddCalObjButton = uibutton(app.Panel, 'push');
            app.AddCalObjButton.Enable = 'off';
            app.AddCalObjButton.Tooltip = {'In next version of the app, this button will activate a new window to  create a new calibration object to be listed in the "select calibration object" drop-down menu.'};
            app.AddCalObjButton.Text = 'Add';
            app.ConfirmCalObjButton = uibutton(app.Panel, 'push');
            app.ConfirmCalObjButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCalObjButtonPushed, true);
            app.ConfirmCalObjButton.Text = 'Confirm';
            app.SbjPathEditField = uieditfield(app.Panel, 'text');
            app.SbjPathEditField.Tooltip = {'You do not necessarily need to select a subject folder. If you do so and the folder contains any information on the subject configuration (whether he/she has any amputations or not), the software is using those info to pre-set the "Body segment panel" and it automatically suggests to save the final configuration in that folder. If you do not, you will be asked to select the saving folder later.'};
            app.SbjPathEditField.Placeholder = 'No pre-selected subject folder...';
            app.SbjBrowseButton = uibutton(app.Panel, 'push');
            app.SbjBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SbjBrowseButtonPushed, true);
            app.SbjBrowseButton.Tooltip = {'You do not necessarily need to select a subject folder. If you do so and the folder contains any information on the subject configuration (whether he/she has any amputations or not), the software is using those info to pre-set the "Body segment panel" and it automatically suggests to save the final configuration in that folder. If you do not, you will be asked to select the saving folder later.'};
            app.SbjBrowseButton.Text = 'Select subject folder';
            app.TaskSelectionButtonGroup = uibuttongroup(app.Panel);
            app.TaskSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TaskSelectionButtonGroupSelectionChanged, true);
            app.TaskSelectionButtonGroup.Title = 'Task Selection';
            app.WalkingButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.WalkingButton.Text = 'Walking';
            app.WalkingButton.Value = true;
            app.RunningButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.RunningButton.Text = 'Running';
            app.LongjumpingButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.LongjumpingButton.Enable = 'off';
            app.LongjumpingButton.Text = 'Long jumping';
            app.GeneralButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.GeneralButton.Enable = 'off';
            app.GeneralButton.Text = 'General';
            app.MainsettingsLabel = uilabel(app.UIFigure);
            app.MainsettingsLabel.Text = '   Main settings';
            app.OperationsLabel = uilabel(app.UIFigure);
            app.OperationsLabel.Text = '   Operations';
            app.SavingLabel = uilabel(app.UIFigure);
            app.SavingLabel.Text = '   Saving';
            app.LogpanelPanel = uipanel(app.UIFigure);
            app.LogpanelPanel.Title = 'Log panel';
            app.LogTextArea = uitextarea(app.LogpanelPanel);
            app.LogTextArea.Editable = 'off';
            app.ClearlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ExportlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ExportlogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlogButtonPushed, true);
            app.ExportlogButton.Text = 'Export log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OlympiaConfigurator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end